classdef SAGA_Data_Visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAGADataViewerUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        UnipolarStreamTab            matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        UnipolarStreamSettingsPanel  matlab.ui.container.Panel
        AllChannelsSwitch            matlab.ui.control.ToggleSwitch
        AllChannelsSwitchLabel       matlab.ui.control.Label
        UnipolarStreamChannelsPanel  matlab.ui.container.Panel
        UNI64SwitchLabel             matlab.ui.control.Label
        UNI64Switch                  matlab.ui.control.Switch
        UNI63SwitchLabel             matlab.ui.control.Label
        UNI63Switch                  matlab.ui.control.Switch
        UNI62SwitchLabel             matlab.ui.control.Label
        UNI62Switch                  matlab.ui.control.Switch
        UNI61SwitchLabel             matlab.ui.control.Label
        UNI61Switch                  matlab.ui.control.Switch
        UNI60SwitchLabel             matlab.ui.control.Label
        UNI60Switch                  matlab.ui.control.Switch
        UNI59SwitchLabel             matlab.ui.control.Label
        UNI59Switch                  matlab.ui.control.Switch
        UNI58SwitchLabel             matlab.ui.control.Label
        UNI58Switch                  matlab.ui.control.Switch
        UNI57SwitchLabel             matlab.ui.control.Label
        UNI57Switch                  matlab.ui.control.Switch
        UNI56SwitchLabel             matlab.ui.control.Label
        UNI56Switch                  matlab.ui.control.Switch
        UNI55SwitchLabel             matlab.ui.control.Label
        UNI55Switch                  matlab.ui.control.Switch
        UNI54SwitchLabel             matlab.ui.control.Label
        UNI54Switch                  matlab.ui.control.Switch
        UNI53SwitchLabel             matlab.ui.control.Label
        UNI53Switch                  matlab.ui.control.Switch
        UNI52SwitchLabel             matlab.ui.control.Label
        UNI52Switch                  matlab.ui.control.Switch
        UNI51SwitchLabel             matlab.ui.control.Label
        UNI51Switch                  matlab.ui.control.Switch
        UNI50SwitchLabel             matlab.ui.control.Label
        UNI50Switch                  matlab.ui.control.Switch
        UNI49SwitchLabel             matlab.ui.control.Label
        UNI49Switch                  matlab.ui.control.Switch
        UNI48SwitchLabel             matlab.ui.control.Label
        UNI48Switch                  matlab.ui.control.Switch
        UNI47SwitchLabel             matlab.ui.control.Label
        UNI47Switch                  matlab.ui.control.Switch
        UNI46SwitchLabel             matlab.ui.control.Label
        UNI46Switch                  matlab.ui.control.Switch
        UNI45SwitchLabel             matlab.ui.control.Label
        UNI45Switch                  matlab.ui.control.Switch
        UNI44SwitchLabel             matlab.ui.control.Label
        UNI44Switch                  matlab.ui.control.Switch
        UNI43SwitchLabel             matlab.ui.control.Label
        UNI43Switch                  matlab.ui.control.Switch
        UNI42SwitchLabel             matlab.ui.control.Label
        UNI42Switch                  matlab.ui.control.Switch
        UNI41SwitchLabel             matlab.ui.control.Label
        UNI41Switch                  matlab.ui.control.Switch
        UNI40SwitchLabel             matlab.ui.control.Label
        UNI40Switch                  matlab.ui.control.Switch
        UNI39SwitchLabel             matlab.ui.control.Label
        UNI39Switch                  matlab.ui.control.Switch
        UNI38SwitchLabel             matlab.ui.control.Label
        UNI38Switch                  matlab.ui.control.Switch
        UNI37SwitchLabel             matlab.ui.control.Label
        UNI37Switch                  matlab.ui.control.Switch
        UNI36SwitchLabel             matlab.ui.control.Label
        UNI36Switch                  matlab.ui.control.Switch
        UNI35SwitchLabel             matlab.ui.control.Label
        UNI35Switch                  matlab.ui.control.Switch
        UNI34SwitchLabel             matlab.ui.control.Label
        UNI34Switch                  matlab.ui.control.Switch
        UNI33Switch_2Label           matlab.ui.control.Label
        UNI33Switch                  matlab.ui.control.Switch
        UNI32SwitchLabel             matlab.ui.control.Label
        UNI32Switch                  matlab.ui.control.Switch
        UNI31SwitchLabel             matlab.ui.control.Label
        UNI31Switch                  matlab.ui.control.Switch
        UNI30SwitchLabel             matlab.ui.control.Label
        UNI30Switch                  matlab.ui.control.Switch
        UNI29SwitchLabel             matlab.ui.control.Label
        UNI29Switch                  matlab.ui.control.Switch
        UNI28SwitchLabel             matlab.ui.control.Label
        UNI28Switch                  matlab.ui.control.Switch
        UNI27SwitchLabel             matlab.ui.control.Label
        UNI27Switch                  matlab.ui.control.Switch
        UNI26SwitchLabel             matlab.ui.control.Label
        UNI26Switch                  matlab.ui.control.Switch
        UNI25SwitchLabel             matlab.ui.control.Label
        UNI25Switch                  matlab.ui.control.Switch
        UNI24SwitchLabel             matlab.ui.control.Label
        UNI24Switch                  matlab.ui.control.Switch
        UNI23SwitchLabel             matlab.ui.control.Label
        UNI23Switch                  matlab.ui.control.Switch
        UNI22SwitchLabel             matlab.ui.control.Label
        UNI22Switch                  matlab.ui.control.Switch
        UNI21SwitchLabel             matlab.ui.control.Label
        UNI21Switch                  matlab.ui.control.Switch
        UNI20SwitchLabel             matlab.ui.control.Label
        UNI20Switch                  matlab.ui.control.Switch
        UNI19SwitchLabel             matlab.ui.control.Label
        UNI19Switch                  matlab.ui.control.Switch
        UNI18SwitchLabel             matlab.ui.control.Label
        UNI18Switch                  matlab.ui.control.Switch
        UNI17SwitchLabel             matlab.ui.control.Label
        UNI17Switch                  matlab.ui.control.Switch
        UNI16SwitchLabel             matlab.ui.control.Label
        UNI16Switch                  matlab.ui.control.Switch
        UNI15SwitchLabel             matlab.ui.control.Label
        UNI15Switch                  matlab.ui.control.Switch
        UNI14SwitchLabel             matlab.ui.control.Label
        UNI14Switch                  matlab.ui.control.Switch
        UNI13SwitchLabel             matlab.ui.control.Label
        UNI13Switch                  matlab.ui.control.Switch
        UNI12SwitchLabel             matlab.ui.control.Label
        UNI12Switch                  matlab.ui.control.Switch
        UNI11SwitchLabel             matlab.ui.control.Label
        UNI11Switch                  matlab.ui.control.Switch
        UNI10SwitchLabel             matlab.ui.control.Label
        UNI10Switch                  matlab.ui.control.Switch
        UNI09SwitchLabel             matlab.ui.control.Label
        UNI09Switch                  matlab.ui.control.Switch
        UNI08SwitchLabel             matlab.ui.control.Label
        UNI08Switch                  matlab.ui.control.Switch
        UNI07SwitchLabel             matlab.ui.control.Label
        UNI07Switch                  matlab.ui.control.Switch
        UNI06SwitchLabel             matlab.ui.control.Label
        UNI06Switch                  matlab.ui.control.Switch
        UNI05SwitchLabel             matlab.ui.control.Label
        UNI05Switch                  matlab.ui.control.Switch
        UNI04SwitchLabel             matlab.ui.control.Label
        UNI04Switch                  matlab.ui.control.Switch
        UNI03SwitchLabel             matlab.ui.control.Label
        UNI03Switch                  matlab.ui.control.Switch
        UNI02SwitchLabel             matlab.ui.control.Label
        UNI02Switch                  matlab.ui.control.Switch
        UNI01Switch                  matlab.ui.control.Switch
        UNI01SwitchLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        BipolarStreamTab             matlab.ui.container.Tab
        UnipolarAverageTab           matlab.ui.container.Tab
        BipolarAverageTab            matlab.ui.container.Tab
        UnipolarRasterTab            matlab.ui.container.Tab
        ICARasterTab                 matlab.ui.container.Tab
        RMSContourTab                matlab.ui.container.Tab
    end

    
    properties (GetAccess = public, SetAccess = protected)
        Tag  % Either "A" or "B"
        cfg  % Parsed configuration struct
    end
    
    properties (Hidden, GetAccess = public, SetAccess = protected)
        lab_expr
    end
    
    methods (Access = private)
        function disable_unipolar_stream(app, chnum)
            %DISABLE_UNIPOLAR_STREAM  Disable the selected channel(s)
            %
            % Syntax:
            %   app.disable_unipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to disable.
            app.cfg.channels.en.UNI(chnum) = false(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.UnipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                h.Value = 'Off';
            end
        end
        
        function enable_unipolar_stream(app, chnum)
            %ENABLE_UNIPOLAR_STREAM  Enable the selected channel(s)
            %
            % Syntax:
            %   app.enable_unipolar_stream(chnum)
            %
            % Inputs:
            %   chnum - Scalar or vector of integers indicating 1-indexed
            %               channel-number(s) to enable.
            app.cfg.channels.en.UNI(chnum) = true(size(chnum));
            for ii = 1:numel(chnum)
                h = findobj(app.UnipolarStreamChannelsPanel.Children, 'Tag', sprintf('%d', chnum(ii)), 'Type', 'uiswitch');
                h.Value = 'On';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config, tag, varargin)
            app.Tag = tag;
            set(app.SAGADataViewerUIFigure, ...
                'Name', sprintf("SAGA-%s Data Viewer", app.Tag), ...
                'Tag', sprintf('fig.DataViewer.%s', app.Tag));
            app.cfg = struct('SAGA', config.SAGA.(tag), ...
                             'Default', config.Default, ...
                             'Server', config.Server);            
            f = setdiff(string(fieldnames(app.cfg.SAGA.Channels)), "n");
            for iF = 1:numel(f)
                app.cfg.SAGA.Channels.en.(f(iF)) = false(size(app.cfg.SAGA.Channels.(f(iF))));
            end
            
        end

        % Value changed function: AllChannelsSwitch
        function AllChannelsSwitchValueChanged(app, event)
            if strcmpi(event.PreviousValue, 'On')
                 app.disable_unipolar_stream(1:64);
            else
                app.enable_unipolar_stream(1:64);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAGADataViewerUIFigure = uifigure('Visible', 'off');
            app.SAGADataViewerUIFigure.Name = 'SAGA Data Viewer';
            app.SAGADataViewerUIFigure.Icon = 'outline_live_tv_black_24dp.png';
            app.TabGroup = uitabgroup(app.SAGADataViewerUIFigure);
            app.UnipolarStreamTab = uitab(app.TabGroup);
            app.UnipolarStreamTab.Title = 'Unipolar Stream';
            app.GridLayout = uigridlayout(app.UnipolarStreamTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Unipolar Streams')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UnipolarStreamChannelsPanel = uipanel(app.GridLayout);
            app.UnipolarStreamChannelsPanel.Title = 'Unipolar Stream Channels';
            app.UNI01SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI01SwitchLabel.Text = 'UNI01';
            app.UNI01Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI01Switch.Tag = '1';
            app.UNI02Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI02Switch.Tag = '2';
            app.UNI02SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI02SwitchLabel.Text = 'UNI02';
            app.UNI03Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI03Switch.Tag = '3';
            app.UNI03SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI03SwitchLabel.Text = 'UNI03';
            app.UNI04Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI04Switch.Tag = '4';
            app.UNI04SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI04SwitchLabel.Tag = '4';
            app.UNI04SwitchLabel.Text = 'UNI04';
            app.UNI05Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI05Switch.Tag = '5';
            app.UNI05SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI05SwitchLabel.Tag = '5';
            app.UNI05SwitchLabel.Text = 'UNI05';
            app.UNI06Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI06Switch.Tag = '6';
            app.UNI06SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI06SwitchLabel.Tag = '6';
            app.UNI06SwitchLabel.Text = 'UNI06';
            app.UNI07Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI07Switch.Tag = '7';
            app.UNI07SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI07SwitchLabel.Tag = '7';
            app.UNI07SwitchLabel.Text = 'UNI07';
            app.UNI08Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI08Switch.Tag = '8';
            app.UNI08SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI08SwitchLabel.Tag = '8';
            app.UNI08SwitchLabel.Text = 'UNI08';
            app.UNI09Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI09Switch.Tag = '9';
            app.UNI09SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI09SwitchLabel.Text = 'UNI09';
            app.UNI10Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI10Switch.Tag = '10';
            app.UNI10SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI10SwitchLabel.Text = 'UNI10';
            app.UNI11Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI11Switch.Tag = '11';
            app.UNI11SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI11SwitchLabel.Text = 'UNI11';
            app.UNI12Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI12Switch.Tag = '12';
            app.UNI12SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI12SwitchLabel.Tag = '4';
            app.UNI12SwitchLabel.Text = 'UNI12';
            app.UNI13Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI13Switch.Tag = '13';
            app.UNI13SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI13SwitchLabel.Tag = '5';
            app.UNI13SwitchLabel.Text = 'UNI13';
            app.UNI14Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI14Switch.Tag = '14';
            app.UNI14SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI14SwitchLabel.Tag = '6';
            app.UNI14SwitchLabel.Text = 'UNI14';
            app.UNI15Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI15Switch.Tag = '15';
            app.UNI15SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI15SwitchLabel.Tag = '7';
            app.UNI15SwitchLabel.Text = 'UNI15';
            app.UNI16Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI16Switch.Tag = '16';
            app.UNI16SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI16SwitchLabel.Tag = '8';
            app.UNI16SwitchLabel.Text = 'UNI16';
            app.UNI17Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI17Switch.Tag = '17';
            app.UNI17SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI17SwitchLabel.Text = 'UNI17';
            app.UNI18Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI18Switch.Tag = '18';
            app.UNI18SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI18SwitchLabel.Text = 'UNI18';
            app.UNI19Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI19Switch.Tag = '19';
            app.UNI19SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI19SwitchLabel.Text = 'UNI19';
            app.UNI20Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI20Switch.Tag = '20';
            app.UNI20SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI20SwitchLabel.Tag = '4';
            app.UNI20SwitchLabel.Text = 'UNI20';
            app.UNI21Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI21Switch.Tag = '21';
            app.UNI21SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI21SwitchLabel.Tag = '5';
            app.UNI21SwitchLabel.Text = 'UNI21';
            app.UNI22Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI22Switch.Tag = '22';
            app.UNI22SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI22SwitchLabel.Tag = '6';
            app.UNI22SwitchLabel.Text = 'UNI22';
            app.UNI23Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI23Switch.Tag = '23';
            app.UNI23SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI23SwitchLabel.Tag = '7';
            app.UNI23SwitchLabel.Text = 'UNI23';
            app.UNI24Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI24Switch.Tag = '24';
            app.UNI24SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI24SwitchLabel.Tag = '8';
            app.UNI24SwitchLabel.Text = 'UNI24';
            app.UNI25Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI25Switch.Tag = '25';
            app.UNI25SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI25SwitchLabel.Text = 'UNI25';
            app.UNI26Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI26Switch.Tag = '26';
            app.UNI26SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI26SwitchLabel.Text = 'UNI26';
            app.UNI27Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI27Switch.Tag = '27';
            app.UNI27SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI27SwitchLabel.Text = 'UNI27';
            app.UNI28Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI28Switch.Tag = '28';
            app.UNI28SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI28SwitchLabel.Tag = '4';
            app.UNI28SwitchLabel.Text = 'UNI28';
            app.UNI29Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI29Switch.Tag = '29';
            app.UNI29SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI29SwitchLabel.Tag = '5';
            app.UNI29SwitchLabel.Text = 'UNI29';
            app.UNI30Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI30Switch.Tag = '30';
            app.UNI30SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI30SwitchLabel.Tag = '6';
            app.UNI30SwitchLabel.Text = 'UNI30';
            app.UNI31Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI31Switch.Tag = '31';
            app.UNI31SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI31SwitchLabel.Tag = '7';
            app.UNI31SwitchLabel.Text = 'UNI31';
            app.UNI32Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI32Switch.Tag = '32';
            app.UNI32SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI32SwitchLabel.Tag = '8';
            app.UNI32SwitchLabel.Text = 'UNI32';
            app.UNI33Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI33Switch.Tag = '33';
            app.UNI33Switch_2Label = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI33Switch_2Label.Text = 'UNI33';
            app.UNI34Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI34Switch.Tag = '34';
            app.UNI34SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI34SwitchLabel.Text = 'UNI34';
            app.UNI35Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI35Switch.Tag = '35';
            app.UNI35SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI35SwitchLabel.Text = 'UNI35';
            app.UNI36Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI36Switch.Tag = '36';
            app.UNI36SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI36SwitchLabel.Tag = '4';
            app.UNI36SwitchLabel.Text = 'UNI36';
            app.UNI37Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI37Switch.Tag = '37';
            app.UNI37SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI37SwitchLabel.Tag = '5';
            app.UNI37SwitchLabel.Text = 'UNI37';
            app.UNI38Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI38Switch.Tag = '38';
            app.UNI38SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI38SwitchLabel.Tag = '6';
            app.UNI38SwitchLabel.Text = 'UNI38';
            app.UNI39Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI39Switch.Tag = '39';
            app.UNI39SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI39SwitchLabel.Tag = '7';
            app.UNI39SwitchLabel.Text = 'UNI39';
            app.UNI40Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI40Switch.Tag = '40';
            app.UNI40SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI40SwitchLabel.Tag = '8';
            app.UNI40SwitchLabel.Text = 'UNI40';
            app.UNI41Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI41Switch.Tag = '41';
            app.UNI41SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI41SwitchLabel.Text = 'UNI41';
            app.UNI42Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI42Switch.Tag = '42';
            app.UNI42SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI42SwitchLabel.Text = 'UNI42';
            app.UNI43Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI43Switch.Tag = '43';
            app.UNI43SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI43SwitchLabel.Text = 'UNI43';
            app.UNI44Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI44Switch.Tag = '44';
            app.UNI44SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI44SwitchLabel.Tag = '4';
            app.UNI44SwitchLabel.Text = 'UNI44';
            app.UNI45Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI45Switch.Tag = '45';
            app.UNI45SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI45SwitchLabel.Tag = '5';
            app.UNI45SwitchLabel.Text = 'UNI45';
            app.UNI46Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI46Switch.Tag = '46';
            app.UNI46SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI46SwitchLabel.Tag = '6';
            app.UNI46SwitchLabel.Text = 'UNI46';
            app.UNI47Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI47Switch.Tag = '47';
            app.UNI47SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI47SwitchLabel.Tag = '7';
            app.UNI47SwitchLabel.Text = 'UNI47';
            app.UNI48Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI48Switch.Tag = '48';
            app.UNI48SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI48SwitchLabel.Tag = '8';
            app.UNI48SwitchLabel.Text = 'UNI48';
            app.UNI49Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI49Switch.Tag = '49';
            app.UNI49SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI49SwitchLabel.Text = 'UNI49';
            app.UNI50Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI50Switch.Tag = '50';
            app.UNI50SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI50SwitchLabel.Text = 'UNI50';
            app.UNI51Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI51Switch.Tag = '51';
            app.UNI51SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI51SwitchLabel.Text = 'UNI51';
            app.UNI52Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI52Switch.Tag = '52';
            app.UNI52SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI52SwitchLabel.Tag = '4';
            app.UNI52SwitchLabel.Text = 'UNI52';
            app.UNI53Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI53Switch.Tag = '53';
            app.UNI53SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI53SwitchLabel.Tag = '5';
            app.UNI53SwitchLabel.Text = 'UNI53';
            app.UNI54Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI54Switch.Tag = '54';
            app.UNI54SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI54SwitchLabel.Tag = '6';
            app.UNI54SwitchLabel.Text = 'UNI54';
            app.UNI55Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI55Switch.Tag = '55';
            app.UNI55SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI55SwitchLabel.Tag = '7';
            app.UNI55SwitchLabel.Text = 'UNI55';
            app.UNI56Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI56Switch.Tag = '56';
            app.UNI56SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI56SwitchLabel.Tag = '8';
            app.UNI56SwitchLabel.Text = 'UNI56';
            app.UNI57Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI57Switch.Tag = '57';
            app.UNI57SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI57SwitchLabel.Text = 'UNI57';
            app.UNI58Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI58Switch.Tag = '58';
            app.UNI58SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI58SwitchLabel.Text = 'UNI58';
            app.UNI59Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI59Switch.Tag = '59';
            app.UNI59SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI59SwitchLabel.Text = 'UNI59';
            app.UNI60Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI60Switch.Tag = '60';
            app.UNI60SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI60SwitchLabel.Tag = '4';
            app.UNI60SwitchLabel.Text = 'UNI60';
            app.UNI61Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI61Switch.Tag = '61';
            app.UNI61SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI61SwitchLabel.Tag = '5';
            app.UNI61SwitchLabel.Text = 'UNI61';
            app.UNI62Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI62Switch.Tag = '62';
            app.UNI62SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI62SwitchLabel.Tag = '6';
            app.UNI62SwitchLabel.Text = 'UNI62';
            app.UNI63Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI63Switch.Tag = '63';
            app.UNI63SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI63SwitchLabel.Tag = '7';
            app.UNI63SwitchLabel.Text = 'UNI63';
            app.UNI64Switch = uiswitch(app.UnipolarStreamChannelsPanel, 'slider');
            app.UNI64Switch.Tag = '64';
            app.UNI64SwitchLabel = uilabel(app.UnipolarStreamChannelsPanel);
            app.UNI64SwitchLabel.Tag = '8';
            app.UNI64SwitchLabel.Text = 'UNI64';
            app.UnipolarStreamSettingsPanel = uipanel(app.GridLayout);
            app.UnipolarStreamSettingsPanel.Title = 'Unipolar Stream Settings';
            app.AllChannelsSwitchLabel = uilabel(app.UnipolarStreamSettingsPanel);
            app.AllChannelsSwitchLabel.Text = 'All Channels';
            app.AllChannelsSwitch = uiswitch(app.UnipolarStreamSettingsPanel, 'toggle');
            app.AllChannelsSwitch.ValueChangedFcn = createCallbackFcn(app, @AllChannelsSwitchValueChanged, true);
            app.BipolarStreamTab = uitab(app.TabGroup);
            app.BipolarStreamTab.Title = 'Bipolar Stream';
            app.UnipolarAverageTab = uitab(app.TabGroup);
            app.UnipolarAverageTab.Title = 'Unipolar Average';
            app.BipolarAverageTab = uitab(app.TabGroup);
            app.BipolarAverageTab.Title = 'Bipolar Average';
            app.UnipolarRasterTab = uitab(app.TabGroup);
            app.UnipolarRasterTab.Title = 'Unipolar Raster';
            app.ICARasterTab = uitab(app.TabGroup);
            app.ICARasterTab.Title = 'ICA Raster';
            app.RMSContourTab = uitab(app.TabGroup);
            app.RMSContourTab.Title = 'RMS Contour';
            % Show the figure after all components are created
            app.SAGADataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAGA_Data_Visualizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAGADataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAGADataViewerUIFigure)
        end
    end
end